classdef ObtainSkeletonPoints_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Panel        matlab.ui.container.Panel
        UIAxes       matlab.ui.control.UIAxes
        Label        matlab.ui.control.Label
        EditField    matlab.ui.control.NumericEditField
        Label_2      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
        Label_3      matlab.ui.control.Label
        Label_4      matlab.ui.control.Label
        TabGroup     matlab.ui.container.TabGroup
        Tab          matlab.ui.container.Tab
        UITable      matlab.ui.control.Table
        Tab_2        matlab.ui.container.Tab
        UITable_2    matlab.ui.control.Table
        Panel_2      matlab.ui.container.Panel
        Button_8     matlab.ui.control.Button
        Button_9     matlab.ui.control.Button
        Button_12    matlab.ui.control.Button
        Button_11    matlab.ui.control.Button
    end


    properties (Access = private)
        mainapp %主程序
        
        SkeletonData_plus %骨架曲线n*2矩阵, 没有初始0点
        SkeletonData_minus %骨架曲线n*2矩阵
        
        Num_pluscircle = 0 %正圈数
        Num_minuscircle  = 0 %负圈数
        
        Force %力列向量
        Disp %位移列向量
        ZeroPoints %原来的零点

        nowZeroPoint %当前点
        lastZeroPoint %上一点
    end

    methods (Access = private)
    
        function plotNowCircle(app,lastZeroP,nowZeroP)
            %根据当前0点绘制滞回环, 绘制骨架点
            if nowZeroP == 1
                cla(app.UIAxes);   
            elseif nowZeroP == 2 %初始状态
                hold(app.UIAxes,'on');
                %滞回曲线
                x=app.Disp(app.ZeroPoints(1):app.ZeroPoints(2),1)';
                y=app.Force(app.ZeroPoints(1):app.ZeroPoints(2),1)';
                a=plot(app.UIAxes,x,y);
                a.Color='k'; a.LineWidth=2 ;
                %骨架点
                tempDispVector=app.Disp(app.ZeroPoints(1):app.ZeroPoints(2),1)';
                tempForceVector=app.Force(app.ZeroPoints(1):app.ZeroPoints(2),1)';
                if abs(max(tempDispVector) ) > abs(min(tempDispVector) )
                    [M,I] = max(tempDispVector);
                else
                    [M,I] = min(tempDispVector);
                end
                if M > 0
                    %如果是正值
                    b=scatter(app.UIAxes,app.SkeletonData_plus(app.Num_pluscircle,2),app.SkeletonData_plus(app.Num_pluscircle,1));
                else
                    %负值
                    b=scatter(app.UIAxes,app.SkeletonData_minus(app.Num_minuscircle,2),app.SkeletonData_minus(app.Num_minuscircle,1));
                end
                b.Marker='o'; 
                b.MarkerFaceColor='r' ; 
                b.MarkerEdgeColor='r' ;
                b.SizeData=50 ;
                
% %                 %扩大x,y轴范围
% %                 app.UIAxes.XLimMode='auto';
% %                 app.UIAxes.YLimMode='auto';
% %                 app.UIAxes.XLim=app.UIAxes.XLim.*1.1;
% %                 app.UIAxes.YLim=app.UIAxes.YLim.*1.1;
%                 %确定滑块的位置
%                 %滑块范围
%                 dx=app.UIAxes.XLim(2)-app.UIAxes.XLim(1);
%                 middle=(app.UIAxes.XLim(2)+app.UIAxes.XLim(1))./2;
%                 app.Slider.Limits=[middle-dx*1.1,middle+dx*1.1];
%                 dy=app.UIAxes.YLim(2)-app.UIAxes.YLim(1);
%                 middle=(app.UIAxes.YLim(2)+app.UIAxes.YLim(1))./2;
%                 app.Slider_2.Limits=[middle-dy*1.1,middle+dy*1.1];
%                 %滑块位置
%                 app.Slider.Value=M;
%                 app.Slider_2.Value=tempForceVector(I);
            elseif nowZeroP > 2
                hold(app.UIAxes,'on');
                %之前的滞回曲线
                x=app.Disp(app.ZeroPoints(1):app.ZeroPoints(lastZeroP),1)';
                y=app.Force(app.ZeroPoints(1):app.ZeroPoints(lastZeroP),1)';
                c=plot(app.UIAxes,x,y);
                c.Color=[0.5 0.5 0.5]; 
                c.LineWidth=0.5 ;
                %当前的滞回曲线
                x=app.Disp(app.ZeroPoints(lastZeroP):app.ZeroPoints(nowZeroP),1)';
                y=app.Force(app.ZeroPoints(lastZeroP):app.ZeroPoints(nowZeroP),1)';
                a=plot(app.UIAxes,x,y);
                a.Color='k'; a.LineWidth=2 ;
                %骨架点
                tempDispVector=app.Disp(app.ZeroPoints(lastZeroP):app.ZeroPoints(nowZeroP),1)';
                tempForceVector=app.Force(app.ZeroPoints(lastZeroP):app.ZeroPoints(nowZeroP),1)';
                if abs(max(tempDispVector) ) > abs(min(tempDispVector) )
                    [M,I] = max(tempDispVector);
                else
                    [M,I] = min(tempDispVector);
                end
                if M > 0
                    %如果是正值
                    b=scatter(app.UIAxes,app.SkeletonData_plus(app.Num_pluscircle,2),app.SkeletonData_plus(app.Num_pluscircle,1));
                else
                    %负值
                    b=scatter(app.UIAxes,app.SkeletonData_minus(app.Num_minuscircle,2),app.SkeletonData_minus(app.Num_minuscircle,1));
                end
                b.Marker='o'; 
                b.MarkerFaceColor='r' ; 
                b.MarkerEdgeColor='r' ;
                b.SizeData=50 ;
                
% %                 %扩大x,y轴范围
% %                 app.UIAxes.XLimMode='auto';
% %                 app.UIAxes.YLimMode='auto';
% %                 app.UIAxes.XLim=app.UIAxes.XLim.*1.1;
% %                 app.UIAxes.YLim=app.UIAxes.YLim.*1.1;
%                 %确定滑块的位置
%                 %滑块范围
%                 dx=app.UIAxes.XLim(2)-app.UIAxes.XLim(1);
%                 middle=(app.UIAxes.XLim(2)+app.UIAxes.XLim(1))./2;
%                 app.Slider.Limits=[middle-dx*1.1,middle+dx*1.1];
%                 dy=app.UIAxes.YLim(2)-app.UIAxes.YLim(1);
%                 middle=(app.UIAxes.YLim(2)+app.UIAxes.YLim(1))./2;
%                 app.Slider_2.Limits=[middle-dy*1.1,middle+dy*1.1];
%                 %滑块位置
%                 app.Slider.Value=M;
%                 app.Slider_2.Value=tempForceVector(I);
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainapp=mainApp;
            app.Force=mainApp.TestForce;
            app.Disp=mainApp.TestDisp;
            app.ZeroPoints=mainApp.zero;
            
            %获取初始骨架点数据
            app.SkeletonData_plus = mainApp.Skeleton_plus'; app.SkeletonData_plus(1,:) = [];
            app.SkeletonData_minus = mainApp.Skeleton_minus'; app.SkeletonData_minus(1,:) = [];
            %显示到表中
            app.UITable.Data = app.SkeletonData_plus;
            app.UITable_2.Data = app.SkeletonData_minus;
            app.UITable.RowName = 'numbered';
            app.UITable_2.RowName = 'numbered';
            
            %绘图
            app.nowZeroPoint=1 ; %当前0点
            app.lastZeroPoint=0 ;
            plotNowCircle(app,app.lastZeroPoint,app.nowZeroPoint);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            % 下一步
            if app.nowZeroPoint >= size(app.ZeroPoints,2)
                %无法下一步
            elseif app.nowZeroPoint < size(app.ZeroPoints,2)
                %更新当前点
                app.lastZeroPoint=app.nowZeroPoint;
                app.nowZeroPoint=app.nowZeroPoint+1;
                %更新圈数
                tempDispVector=app.Disp(app.ZeroPoints(app.lastZeroPoint):app.ZeroPoints(app.nowZeroPoint),1)';
                if abs(max(tempDispVector) ) > abs(min(tempDispVector) )
                    [M,~] = max(tempDispVector);
                else
                    [M,~] = min(tempDispVector);
                end
                if M > 0
                    app.Num_pluscircle = app.Num_pluscircle+1;
                else
                    app.Num_minuscircle = app.Num_minuscircle+1;
                end
                app.EditField.Value=app.Num_pluscircle;
                app.EditField_2.Value=app.Num_minuscircle;
                if app.nowZeroPoint <= size(app.ZeroPoints,2)
                    %绘图
                    cla(app.UIAxes);
                    plotNowCircle(app,app.lastZeroPoint,app.nowZeroPoint);
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 确定按钮
            
            %补上0点
            app.SkeletonData_plus = vertcat([0,0],app.SkeletonData_plus);
            app.SkeletonData_minus = vertcat([0,0],app.SkeletonData_minus);
            
            %修改mainapp的骨架点数据
            app.mainapp.set_Skeleton(app.SkeletonData_plus', app.SkeletonData_minus');
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %取消按钮
            delete(app)
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            %上一步
            if app.nowZeroPoint <= 2
                %无法上一步
            else
                %更新当前点
                app.lastZeroPoint=app.lastZeroPoint-1;
                app.nowZeroPoint=app.nowZeroPoint-1;
                %更新圈数
                tempDispVector=app.Disp(app.ZeroPoints(app.lastZeroPoint+1):app.ZeroPoints(app.nowZeroPoint+1),1)';
                if abs(max(tempDispVector) ) > abs(min(tempDispVector) )
                    [M,~] = max(tempDispVector);
                else
                    [M,~] = min(tempDispVector);
                end
                if M > 0
                    app.Num_pluscircle = app.Num_pluscircle-1;
                else
                    app.Num_minuscircle = app.Num_minuscircle-1;
                end
                app.EditField.Value=app.Num_pluscircle;
                app.EditField_2.Value=app.Num_minuscircle;
                %绘图
                cla(app.UIAxes);
                plotNowCircle(app,app.lastZeroPoint,app.nowZeroPoint);
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            %手动选点
            x = app.UIAxes.CurrentPoint(1,1);
            y = app.UIAxes.CurrentPoint(1,2);
            %修改SkeletonData_plus
            if abs(max(app.Disp(app.ZeroPoints(app.lastZeroPoint):app.ZeroPoints(app.nowZeroPoint),1))) > ...
                    abs(min(app.Disp(app.ZeroPoints(app.lastZeroPoint):app.ZeroPoints(app.nowZeroPoint),1)))
                %如果当前位移是正值, 修改正值
                app.SkeletonData_plus(app.Num_pluscircle, 1) = y;
                app.SkeletonData_plus(app.Num_pluscircle, 2) = x;
            else
                %修改负值
                app.SkeletonData_minus(app.Num_minuscircle, 1) = y;
                app.SkeletonData_minus(app.Num_minuscircle, 2) = x;
            end
            %修改表中显示显示
            app.UITable.Data = app.SkeletonData_plus;
            app.UITable_2.Data = app.SkeletonData_minus;
            %重新绘图
            cla(app.UIAxes);
            plotNowCircle(app,app.lastZeroPoint,app.nowZeroPoint);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            %重置骨架点
            reset_skeleton(app.mainapp);
            %获取初始骨架点数据
            app.SkeletonData_plus =app.mainapp.Skeleton_plus'; app.SkeletonData_plus(1,:) = [];
            app.SkeletonData_minus = app.mainapp.Skeleton_minus'; app.SkeletonData_minus(1,:) = [];
            %显示到表中
            app.UITable.Data = app.SkeletonData_plus;
            app.UITable_2.Data = app.SkeletonData_minus;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '交互识别滞回环';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '取消';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '图示区';
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, '位移')
            ylabel(app.UIAxes, '力')
            app.Label = uilabel(app.Panel);
            app.Label.Text = '当前正圈数：';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '当前负圈数：';
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '正向骨架点数据';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'力'; '位移'};
            app.UITable.RowName = {''};
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '负向骨架点数据';
            app.UITable_2 = uitable(app.Tab_2);
            app.UITable_2.ColumnName = {'力'; '位移'};
            app.UITable_2.RowName = {''};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '交互区';
            app.Button_8 = uibutton(app.Panel_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '下一步';
            app.Button_9 = uibutton(app.Panel_2, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '上一步';
            app.Button_12 = uibutton(app.Panel_2, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '将上次鼠标点击位置设置为当前骨架点';
            app.Button_11 = uibutton(app.Panel_2, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '重置所有数据';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ObtainSkeletonPoints_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end