classdef ObtainZeropoint_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button         matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Panel          matlab.ui.container.Panel
        UIAxes         matlab.ui.control.UIAxes
        Label          matlab.ui.control.Label
        EditField      matlab.ui.control.NumericEditField
        Label_2        matlab.ui.control.Label
        EditField_2    matlab.ui.control.NumericEditField
        Panel_2        matlab.ui.container.Panel
        Button_8       matlab.ui.control.Button
        ButtonGroup_3  matlab.ui.container.ButtonGroup
        Button_9       matlab.ui.control.RadioButton
        Button_10      matlab.ui.control.RadioButton
    end


    properties (SetAccess = private)
        ModifiedZeroPoints %此app处理后的0点
    end
    
    properties (Access = private)
        mainapp %主程序
        
        Num_pluscircle = 0 %正圈数
        Num_minuscircle  = 0 %负圈数
        
        Force %力列向量
        Disp %位移列向量
        ZeroPoints %原来的零点

        nowZeroPoint %当前点
        lastZeroPoint %上一点
        sign %当前正负,1/0
    end

    methods (Access = private)
    
        function plotNowCircle(app,lastZeroP,nowZeroP)
            %根据当前0点绘制滞回环,并自动判断正负
            
            if nowZeroP == 2 %初始状态
                hold(app.UIAxes,'on');
                %滞回曲线
                x=app.Disp(app.ZeroPoints(1):app.ZeroPoints(2),1)';
                y=app.Force(app.ZeroPoints(1):app.ZeroPoints(2),1)';
                a=plot(app.UIAxes,x,y);
                a.Color='k'; a.LineWidth=2 ;
                %骨架点
                tempDispVector=app.Disp(app.ZeroPoints(1):app.ZeroPoints(2),1)';
                tempForceVector=app.Force(app.ZeroPoints(1):app.ZeroPoints(2),1)';
                if abs(max(tempDispVector) ) > abs(min(tempDispVector) )
                    [M,I] = max(tempDispVector);
                    app.sign=1;
                else
                    [M,I] = min(tempDispVector);
                    app.sign=0;
                end
                b=scatter(app.UIAxes,M,tempForceVector(I));
                b.Marker='o'; 
                b.MarkerFaceColor='r' ; 
                b.MarkerEdgeColor='r' ;
                b.SizeData=50 ;
                
% %                 %扩大x,y轴范围
% %                 app.UIAxes.XLimMode='auto';
% %                 app.UIAxes.YLimMode='auto';
% %                 app.UIAxes.XLim=app.UIAxes.XLim.*1.1;
% %                 app.UIAxes.YLim=app.UIAxes.YLim.*1.1;
%                 %确定滑块的位置
%                 %滑块范围
%                 dx=app.UIAxes.XLim(2)-app.UIAxes.XLim(1);
%                 middle=(app.UIAxes.XLim(2)+app.UIAxes.XLim(1))./2;
%                 app.Slider.Limits=[middle-dx*1.1,middle+dx*1.1];
%                 dy=app.UIAxes.YLim(2)-app.UIAxes.YLim(1);
%                 middle=(app.UIAxes.YLim(2)+app.UIAxes.YLim(1))./2;
%                 app.Slider_2.Limits=[middle-dy*1.1,middle+dy*1.1];
%                 %滑块位置
%                 app.Slider.Value=M;
%                 app.Slider_2.Value=tempForceVector(I);
            elseif nowZeroP > 2
                hold(app.UIAxes,'on');
                %之前的滞回曲线
                x=app.Disp(app.ZeroPoints(1):app.ZeroPoints(lastZeroP),1)';
                y=app.Force(app.ZeroPoints(1):app.ZeroPoints(lastZeroP),1)';
                c=plot(app.UIAxes,x,y);
                c.Color=[0.5 0.5 0.5]; 
                c.LineWidth=0.5 ;
                %当前的滞回曲线
                x=app.Disp(app.ZeroPoints(lastZeroP):app.ZeroPoints(nowZeroP),1)';
                y=app.Force(app.ZeroPoints(lastZeroP):app.ZeroPoints(nowZeroP),1)';
                a=plot(app.UIAxes,x,y);
                a.Color='k'; a.LineWidth=2 ;
                %骨架点
                tempDispVector=app.Disp(app.ZeroPoints(lastZeroP):app.ZeroPoints(nowZeroP),1)';
                tempForceVector=app.Force(app.ZeroPoints(lastZeroP):app.ZeroPoints(nowZeroP),1)';
                if abs(max(tempDispVector) ) > abs(min(tempDispVector) )
                    [M,I] = max(tempDispVector);
                    app.sign=1;
                else
                    [M,I] = min(tempDispVector);
                    app.sign=0;
                end
                b=scatter(app.UIAxes,M,tempForceVector(I));
                b.Marker='o'; 
                b.MarkerFaceColor='r' ; 
                b.MarkerEdgeColor='r' ;
                b.SizeData=50 ;
                
% %                 %扩大x,y轴范围
% %                 app.UIAxes.XLimMode='auto';
% %                 app.UIAxes.YLimMode='auto';
% %                 app.UIAxes.XLim=app.UIAxes.XLim.*1.1;
% %                 app.UIAxes.YLim=app.UIAxes.YLim.*1.1;
%                 %确定滑块的位置
%                 %滑块范围
%                 dx=app.UIAxes.XLim(2)-app.UIAxes.XLim(1);
%                 middle=(app.UIAxes.XLim(2)+app.UIAxes.XLim(1))./2;
%                 app.Slider.Limits=[middle-dx*1.1,middle+dx*1.1];
%                 dy=app.UIAxes.YLim(2)-app.UIAxes.YLim(1);
%                 middle=(app.UIAxes.YLim(2)+app.UIAxes.YLim(1))./2;
%                 app.Slider_2.Limits=[middle-dy*1.1,middle+dy*1.1];
%                 %滑块位置
%                 app.Slider.Value=M;
%                 app.Slider_2.Value=tempForceVector(I);
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainapp=mainApp;
            app.Force=mainApp.TestForce;
            app.Disp=mainApp.TestDisp;
            app.ZeroPoints=mainApp.zero;
            app.ModifiedZeroPoints(1)=mainApp.zero(1);
            
            %绘图
            app.nowZeroPoint=2 ; %当前0点
            app.lastZeroPoint=1 ;
            plotNowCircle(app,app.lastZeroPoint,app.nowZeroPoint);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            % 下一步
            app.Button_8.Enable = 'off';
            
            if app.nowZeroPoint > size(app.ZeroPoints,2)
                warndlg('滞回环数目已到底');
            elseif app.nowZeroPoint <= size(app.ZeroPoints,2)
                if app.Button_9.Value==1  %如果是滞回环
                    %记录0点
                    app.ModifiedZeroPoints=horzcat(app.ModifiedZeroPoints,app.ZeroPoints(app.nowZeroPoint));
                    %更新当前点
                    app.lastZeroPoint=app.nowZeroPoint;
                    app.nowZeroPoint=app.nowZeroPoint+1;
                    %更新圈数
                    if app.sign == 1 %正
                        app.Num_pluscircle = app.Num_pluscircle+1;
                    else 
                        app.Num_minuscircle = app.Num_minuscircle+1;
                    end
                    app.EditField.Value=app.Num_pluscircle;
                    app.EditField_2.Value=app.Num_minuscircle;
                else
                    %更新当前点
                    app.nowZeroPoint=app.nowZeroPoint+1;
                end
                if app.nowZeroPoint <= size(app.ZeroPoints,2)
                    %绘图
                    cla(app.UIAxes);
                    plotNowCircle(app,app.lastZeroPoint,app.nowZeroPoint);
                end
                if app.nowZeroPoint > size(app.ZeroPoints,2)
                    warndlg('滞回环数目已到底');
                end
            end
            
            app.Button_8.Enable = 'on';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 确定按钮
            %修改0点
            app.mainapp.set_zero(app.ModifiedZeroPoints);
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %取消按钮
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '交互识别滞回环';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '取消';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '图示区';
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, '位移')
            ylabel(app.UIAxes, '力')
            app.Label = uilabel(app.Panel);
            app.Label.Text = '正圈数：';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '负圈数：';
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '交互区';
            app.Button_8 = uibutton(app.Panel_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '下一步';
            app.ButtonGroup_3 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_3.Title = '是否构成半圈滞回环：';
            app.Button_9 = uiradiobutton(app.ButtonGroup_3);
            app.Button_9.Text = '是';
            app.Button_9.Value = true;
            app.Button_10 = uiradiobutton(app.ButtonGroup_3);
            app.Button_10.Text = '否';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ObtainZeropoint_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end