classdef OutputFigure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        CheckBox    matlab.ui.control.CheckBox
        CheckBox_2  matlab.ui.control.CheckBox
        CheckBox_4  matlab.ui.control.CheckBox
        CheckBox_5  matlab.ui.control.CheckBox
        Label       matlab.ui.control.Label
        EditField   matlab.ui.control.EditField
        Button      matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
        Button_3    matlab.ui.control.Button
        Panel_2     matlab.ui.container.Panel
        CheckBox_8  matlab.ui.control.CheckBox
        CheckBox_9  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        CallingApp % 主程序
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
           app.CallingApp = mainapp; 
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            path = uigetdir;
            if ischar(path)
                app.EditField.Value=path;
            elseif ~ischar(path)
            end
            figure(app.UIFigure);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            UIFigureCloseRequest(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if isempty(app.EditField.Value)
                errorFigure;
            elseif ~isempty(app.EditField.Value)
                %输出数据1
                filename = strcat(app.EditField.Value, '\骨架曲线结果.csv');
                fileID = fopen(filename,'w');
                %完整骨架曲线
                if app.CheckBox_2.Value==1
                    fseek(fileID, 0, 'eof'); %移至文件末尾
                    fprintf(fileID,'完整骨架曲线: 力-位移');
                    outputResult=app.CallingApp.Skeleton_plus; 
                    dlmwrite(filename,outputResult,'-append','delimiter',',','coffset',0,'roffset',1);
                    fseek(fileID, 0, 'eof'); %移至文件末尾
                    outputResult=app.CallingApp.Skeleton_minus; 
                    dlmwrite(filename,outputResult,'-append','delimiter',',','coffset',0,'roffset',0);
                end
                %单点骨架曲线
                if app.CheckBox.Value==1
                    fseek(fileID, 0, 'eof'); %移至文件末尾
                    fprintf(fileID,'每级加载识别一个点的骨架曲线：力-位移');
                    outputResult=app.CallingApp.OnePointSkeleton_plus;
                    dlmwrite(filename,outputResult,'-append','delimiter',',','coffset',0,'roffset',1);
                    fseek(fileID, 0, 'eof'); %移至文件末尾
                    outputResult=app.CallingApp.OnePointSkeleton_minus;
                    dlmwrite(filename,outputResult,'-append','delimiter',',','coffset',0,'roffset',0);
                end
                %每圈滞回面积
                if app.CheckBox_4.Value==1
                    fseek(fileID, 0, 'eof'); %移至文件末尾
                    fprintf(fileID,'每圈滞回面积：');
                    outputResult=app.CallingApp.Wc;
                    dlmwrite(filename,outputResult,'-append','delimiter',',','coffset',0,'roffset',1);
                end
                %每圈阻尼比
                if app.CheckBox_5.Value==1
                    fseek(fileID, 0, 'eof'); %移至文件末尾
                    fprintf(fileID,'每圈阻尼比：');
                    outputResult=app.CallingApp.kesi;
                    dlmwrite(filename,outputResult,'-append','delimiter',',','coffset',0,'roffset',1);
                end
                %关闭文件
                fclose(fileID);
                
                %平滑滞回曲线
                if app.CheckBox_8.Value==1
                    %输出数据2
                    filename = strcat(app.EditField.Value, '\平滑曲线.csv');
                    fileID = fopen(filename,'w');
                    
                    fseek(fileID, 0, 'eof'); %移至文件末尾
                    fprintf(fileID,'平滑滞回曲线');
                    fprintf(fileID,'\n');
                    fprintf(fileID,'力');fprintf(fileID,',');fprintf(fileID,'位移');
                    outputResult=app.CallingApp.SmoothCurve;
                    dlmwrite(filename,outputResult,'-append','delimiter',',','coffset',0,'roffset',1);
                    
                    %关闭文件
                    fclose(fileID);
                end
                
                
                %按照圈数分开的原始力-位移数据
                if app.CheckBox_9.Value==1
                    %输出数据3
                    filename = strcat(app.EditField.Value, '\原始数据分段.csv');
                    fileID = fopen(filename,'w');
                    
                    zero = app.CallingApp.zero; 
                    disp = app.CallingApp.TestDisp;
                    force = app.CallingApp.TestForce;
                    %每圈
                    for i = 1:(ceil((size(zero,2)-1)/2)-1)
                        fseek(fileID, 0, 'eof'); %移至文件末尾
                        fprintf(fileID,strcat('第 ',num2str(i),' 圈: 力-位移', ','));
                        fprintf(fileID,'\n');
                        dlmwrite(filename,force(zero(i*2-1):zero(2*i+1),1)','-append','delimiter',',','coffset',0,'roffset',0);
                        dlmwrite(filename,disp(zero(i*2-1):zero(2*i+1),1)','-append','delimiter',',','coffset',0,'roffset',0);
                    end
                    %最后一圈或半圈
                    i= i+1;
                    fseek(fileID, 0, 'eof'); %移至文件末尾
                    fprintf(fileID,strcat('第 ',num2str(i),' 圈: 力-位移', ','));
                    fprintf(fileID,'\n');
                    dlmwrite(filename,force(zero(i*2-1):end,1)','-append','delimiter',',','coffset',0,'roffset',0);
                    dlmwrite(filename,disp(zero(i*2-1):end,1)','-append','delimiter',',','coffset',0,'roffset',0);
                    
                    %关闭文件
                    fclose(fileID);
                end
                
                %提示框
                outputSuccessNotice;
                Button_3Pushed(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
               delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '输出';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '结果';
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.Text = '每级一个点的骨架曲线';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.Panel);
            app.CheckBox_2.Text = '完整的骨架曲线';
            app.CheckBox_4 = uicheckbox(app.Panel);
            app.CheckBox_4.Text = '每圈滞回面积';
            app.CheckBox_5 = uicheckbox(app.Panel);
            app.CheckBox_5.Text = '每圈阻尼比';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '输出文件位置：';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择文件夹';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '确定';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '取消';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '处理后的原始数据';
            app.CheckBox_8 = uicheckbox(app.Panel_2);
            app.CheckBox_8.Text = '平滑后的滞回曲线';
            app.CheckBox_9 = uicheckbox(app.Panel_2);
            app.CheckBox_9.Text = {'按照圈数分开的原始'; '力-位移数据'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OutputFigure(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end