classdef SkeletonCurve < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        youtianUIFigure          matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        Label                    matlab.ui.control.Label
        DataPath                 matlab.ui.control.EditField
        Label_4                  matlab.ui.control.Label
        InputFile                matlab.ui.control.Button
        Label_3                  matlab.ui.control.Label
        inputCircles             matlab.ui.control.EditField
        Label_6                  matlab.ui.control.Label
        CalculateButton          matlab.ui.control.Button
        Panel_8                  matlab.ui.container.Panel
        Label_15                 matlab.ui.control.Label
        EditField_6              matlab.ui.control.NumericEditField
        Label_18                 matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        Label_19                 matlab.ui.control.Label
        CalculateButton_3        matlab.ui.control.Button
        txtLabel                 matlab.ui.control.Label
        CalculateButton_4        matlab.ui.control.Button
        Panel_4                  matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        ButtonGroup              matlab.ui.container.ButtonGroup
        Button                   matlab.ui.control.RadioButton
        Button_2                 matlab.ui.control.RadioButton
        CheckBox                 matlab.ui.control.CheckBox
        Label_7                  matlab.ui.control.Label
        CheckBox_2               matlab.ui.control.CheckBox
        CheckBox_3               matlab.ui.control.CheckBox
        Panel_5                  matlab.ui.container.Panel
        Panel_6                  matlab.ui.container.Panel
        Label_8                  matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        Label_9                  matlab.ui.control.Label
        EditField_2              matlab.ui.control.EditField
        Label_10                 matlab.ui.control.Label
        EditField_3              matlab.ui.control.EditField
        Label_11                 matlab.ui.control.Label
        EditField_4              matlab.ui.control.EditField
        Panel_7                  matlab.ui.container.Panel
        Label_21                 matlab.ui.control.Label
        EditField_8              matlab.ui.control.EditField
        Label_20                 matlab.ui.control.Label
        EditField_7              matlab.ui.control.EditField
        Label_22                 matlab.ui.control.Label
        CalculateButton_2        matlab.ui.control.Button
        youtiantongjieducnLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        SkeletonObj
    end

    properties (Access = private)
        TestDisp
        TestForce
        level %加载制度
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CalculateButton_2.Enable='off';
            app.Button_2.Enable='off';
            app.Button_2.Enable='off';
            app.Button.Enable='off';
            app.CheckBox.Enable='off';
            app.CheckBox_2.Enable='off';
            app.CheckBox_3.Enable='off';
            
            app.CalculateButton.Enable='off';
            app.CalculateButton_4.Enable='off';
            app.CalculateButton_3.Enable='off';
            
            app.inputCircles.Enable='off';
        end

        % Button pushed function: InputFile
        function InputFileButtonPushed(app, event)
            [file,path] = uigetfile;
            if ischar(file)
                M=dlmread(strcat(path,file));
                app.TestDisp=M(:,2); 
                app.TestForce=M(:,1);
                app.DataPath.Value=strcat(path,file);
            elseif ~ischar(file)
            end
            figure(app.youtianUIFigure);
            
            %可以输入加载制度了
            app.inputCircles.Enable='on';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            %绘图
            cla(app.UIAxes);
            hold(app.UIAxes,'on');
            
            % 原滞回曲线
            if app.CheckBox.Value==1
                a(1)=plot(app.UIAxes, app.TestDisp',app.TestForce');
                a(1).Color = 'b'; a(1).LineWidth= 0.75 ; 
            end
            
            %骨架曲线绘制和数据
            if app.CheckBox_2.Value==1
                if app.Button.Value == 1
                    if ~isempty(app.SkeletonObj.OnePointSkeleton_plus) %非空才计算
                        %处理0项
                        positiveSke=app.SkeletonObj.OnePointSkeleton_plus(1:2,:);
                        positiveSke(:,positiveSke(2,:)==0)=[];
                        positiveSke=horzcat(zeros(2,1),positiveSke);
                        negativeSke=app.SkeletonObj.OnePointSkeleton_minus(1:2,:);
                        negativeSke(:,negativeSke(2,:)==0)=[];
                        negativeSke=horzcat(zeros(2,1),negativeSke);
                        
                        a(2)=plot(app.UIAxes, positiveSke(2,:),positiveSke(1,:));
                        a(2).Color = 'k'; a(2).LineWidth= 2 ;
                        a(3)=plot(app.UIAxes, negativeSke(2,:),negativeSke(1,:));   
                        a(3).Color = 'k'; a(3).LineWidth= 2 ;
                        
                        %显示输出
                        app.EditField.Value=num2str(positiveSke(1,:),3);
                        app.EditField_2.Value=num2str(positiveSke(2,:),3);
                        app.EditField_3.Value=num2str(negativeSke(1,:),3);
                        app.EditField_4.Value=num2str(negativeSke(2,:),3);
                        app.EditField_7.Value=num2str(app.SkeletonObj.Wc(1,:),3);
                        app.EditField_8.Value=num2str(app.SkeletonObj.kesi(1,:),3);
                    else 
                        app.EditField.Value="";
                        app.EditField_2.Value="";
                        app.EditField_3.Value="";
                        app.EditField_4.Value="";
                        app.EditField_7.Value="";
                        app.EditField_8.Value="";
                    end
                    
                elseif app.Button_2.Value == 1 
                    %处理0项
                    positiveSke=app.SkeletonObj.Skeleton_plus(1:2,:);
                    positiveSke(:,positiveSke(2,:)==0)=[];
                    positiveSke=horzcat(zeros(2,1),positiveSke);
                    negativeSke=app.SkeletonObj.Skeleton_minus(1:2,:);
                    negativeSke(:,negativeSke(2,:)==0)=[];
                    negativeSke=horzcat(zeros(2,1),negativeSke);
                    
                    a(2)=plot(app.UIAxes, positiveSke(2,:),positiveSke(1,:));
                    a(2).Color = 'k'; a(2).LineWidth= 2 ;
                    a(3)=plot(app.UIAxes,negativeSke(2,:),negativeSke(1,:));   
                    a(3).Color = 'k'; a(3).LineWidth= 2 ;
                    
                    %显示输出
                    app.EditField.Value=num2str(positiveSke(1,:),3);
                    app.EditField_2.Value=num2str(positiveSke(2,:),3);
                    app.EditField_3.Value=num2str(negativeSke(1,:),3);
                    app.EditField_4.Value=num2str(negativeSke(2,:),3);
                end
            end
            
            %平滑滞回曲线
            if app.CheckBox_3.Value==1
                a(4)=plot(app.UIAxes, app.SkeletonObj.SmoothCurve(:,2)',app.SkeletonObj.SmoothCurve(:,1)');
                a(4).Color = 'r'; a(4).LineWidth= 0.75 ;
            end
            

        end

        % Value changed function: inputCircles
        function inputCirclesValueChanged(app, event)
            value = app.inputCircles.Value;
            app.level=str2num(value);
            %可以进行交互了
            app.CalculateButton_3.Enable='on';
            app.EditField_6.Enable='on';
            app.Slider.Enable='on';
            
            %创建对象
            app.SkeletonObj = SkeletonCurveCalcV3(app.TestDisp,app.TestForce,app.level);
            app.SkeletonObj.set_PeakTol(app.EditField_6.Value);
            
            %显示图像
            CalculateButtonPushed(app, event);
            
            %显示按钮可见
            app.CalculateButton_2.Enable='on';
            app.Button_2.Enable='on';
            app.Button.Enable='on';
            app.CheckBox.Enable='on';
            app.CheckBox_2.Enable='on';
            app.CheckBox_3.Enable='on';
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            CalculateButtonPushed(app);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            CalculateButtonPushed(app);
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            CalculateButtonPushed(app);
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            CalculateButtonPushed(app);
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            %创建输出界面
            output(app.SkeletonObj);
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            %交互识别滞回环
            ObtainZeropoint(app.SkeletonObj);
            app.CalculateButton_4.Enable='on';
            %更新显示
            CalculateButtonPushed(app);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            %修改对象的值
            app.SkeletonObj.set_PeakTol(value);
            %显示
            CalculateButtonPushed(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            %重新计算
            set_smooth(app.SkeletonObj, value);
            %显示
            CalculateButtonPushed(app);
        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
            ObtainSkeletonPoints(app.SkeletonObj);
            %显示
            app.CalculateButton.Enable='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.youtianUIFigure = uifigure('Visible', 'off');
            app.youtianUIFigure.Name = '滞回曲线处理工具-youtian';
            app.Panel = uipanel(app.youtianUIFigure);
            app.Panel.Title = '数据输入';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.DataPath = uieditfield(app.Panel, 'text');
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '力-位移曲线：';
            app.InputFile = uibutton(app.Panel, 'push');
            app.InputFile.ButtonPushedFcn = createCallbackFcn(app, @InputFileButtonPushed, true);
            app.InputFile.Text = '选择输入文件';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '每级加载圈数';
            app.inputCircles = uieditfield(app.Panel, 'text');
            app.inputCircles.ValueChangedFcn = createCallbackFcn(app, @inputCirclesValueChanged, true);
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '例如：3 3 2';
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = '更新图像';
            app.Panel_8 = uipanel(app.Panel);
            app.Panel_8.Title = '参数设置';
            app.Label_15 = uilabel(app.Panel_8);
            app.Label_15.Text = '最小/最大目标位移 > ';
            app.EditField_6 = uieditfield(app.Panel_8, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Enable = 'off';
            app.EditField_6.Value = 0.02;
            app.Label_18 = uilabel(app.Panel_8);
            app.Label_18.Enable = 'off';
            app.Label_18.Text = '平滑窗口大小';
            app.Slider = uislider(app.Panel_8);
            app.Slider.Limits = [0 200];
            app.Slider.MajorTicks = [0 50 100 150 200];
            app.Slider.MajorTickLabels = {'0', '50', '100', '150', '200'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
            app.Slider.Value = 50;
            app.Label_19 = uilabel(app.Panel);
            app.Label_19.Text = '第一列为力，第二列为位移';
            app.CalculateButton_3 = uibutton(app.Panel, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = {'确定滞回环'; '(同时重置所有数据)'};
            app.txtLabel = uilabel(app.Panel);
            app.txtLabel.Text = 'txt文件, 只要数字';
            app.CalculateButton_4 = uibutton(app.Panel, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = '确定骨架点';
            app.Panel_4 = uipanel(app.youtianUIFigure);
            app.Panel_4.Title = '显示设置';
            app.UIAxes = uiaxes(app.Panel_4);
            title(app.UIAxes, '滞回曲线')
            xlabel(app.UIAxes, '位移')
            ylabel(app.UIAxes, '力')
            app.ButtonGroup = uibuttongroup(app.Panel_4);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '每级识别一个点(必须进行交互识别滞回环才能有效)';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '每级识别所有点';
            app.Button_2.Value = true;
            app.CheckBox = uicheckbox(app.Panel_4);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '原曲线';
            app.CheckBox.Value = true;
            app.Label_7 = uilabel(app.Panel_4);
            app.Label_7.Text = '显示：';
            app.CheckBox_2 = uicheckbox(app.Panel_4);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '骨架曲线';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.Panel_4);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '平滑后滞回曲线';
            app.Panel_5 = uipanel(app.youtianUIFigure);
            app.Panel_5.Title = '输出';
            app.Panel_6 = uipanel(app.Panel_5);
            app.Panel_6.Title = '骨架曲线';
            app.Label_8 = uilabel(app.Panel_6);
            app.Label_8.Text = '正向力：';
            app.EditField = uieditfield(app.Panel_6, 'text');
            app.Label_9 = uilabel(app.Panel_6);
            app.Label_9.Text = '正向位移：';
            app.EditField_2 = uieditfield(app.Panel_6, 'text');
            app.Label_10 = uilabel(app.Panel_6);
            app.Label_10.Text = '负向力：';
            app.EditField_3 = uieditfield(app.Panel_6, 'text');
            app.Label_11 = uilabel(app.Panel_6);
            app.Label_11.Text = '负向位移：';
            app.EditField_4 = uieditfield(app.Panel_6, 'text');
            app.Panel_7 = uipanel(app.Panel_5);
            app.Panel_7.Title = '有效阻尼比';
            app.Label_21 = uilabel(app.Panel_7);
            app.Label_21.Text = '每圈阻尼比：';
            app.EditField_8 = uieditfield(app.Panel_7, 'text');
            app.Label_20 = uilabel(app.Panel_7);
            app.Label_20.Text = '每圈面积：';
            app.EditField_7 = uieditfield(app.Panel_7, 'text');
            app.Label_22 = uilabel(app.Panel_7);
            app.Label_22.Text = '根据《抗规》（12.3.4-1)';
            app.CalculateButton_2 = uibutton(app.Panel_5, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = '输出文件';
            app.youtiantongjieducnLabel = uilabel(app.Panel_5);
            app.youtiantongjieducnLabel.Text = '有问题联系youtian@tongji.edu.cn';
            % Show the figure after all components are created
            app.youtianUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SkeletonCurve

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.youtianUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.youtianUIFigure)
        end
    end
end